classdef selector_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        Tree          matlab.ui.container.Tree
        GridLayout2   matlab.ui.container.GridLayout
        CancelButton  matlab.ui.control.Button
        SelectButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        OnClosedHandle
        SelectItems
        IsList
    end
    
    methods (Access = private)
        
        function results = display_items(app, items)


            function fill_list(node, items)
                if isempty(items)
                    return
                end
                first = app.SelectItems(items(1));
                selected = strings(length(items), width(first));
                selected(1, :) = first;
                if isa(items, 'cell')
                    for i=2:length(items)
                        selected(i, :) = app.SelectItems(items{i});
                    end
                else
                    for i=2:length(items)
                        selected(i, :) = app.SelectItems(items(i));
                    end
                end
            
                item_nodes = matlab.ui.container.TreeNode.empty;
                if isa(items, 'cell')
                    for i=1:length(selected)
                        item_nodes(end+1) = uitreenode(node, 'Text', selected(i, 1), 'NodeData', items{i});
                    end
                else
                    for i=1:length(selected)
                        item_nodes(end+1) = uitreenode(node, 'Text', selected(i, 1), 'NodeData', items(i));
                    end
                end
            end


            if isa(items, 'dictionary')
                app.IsList = 0;
                ks = keys(items);
                for j=1:length(ks)
                    k = ks(j);
                    if isempty(items{k})
                        continue
                    end
                    n = uitreenode(app.Tree, 'Text', k);
                    fill_list(n, items{k});
                end
            else
                IsList = 1;
                fill_list(app.Tree, items);
            end

           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, item_list, on_closed_handle, select_items)
            app.OnClosedHandle = on_closed_handle;
            app.IsList = 1;
            if exist('select_items', 'var')
                app.SelectItems = select_items;
            else
                app.SelectItems = @(x) x;
            end
            app.display_items(item_list);
        end

        % Button pushed function: SelectButton
        function on_select_var(app, event)

            selection = app.Tree.SelectedNodes;
            if app.IsList == 0 && selection.Parent == app.Tree
                error('Select plugin type...');
            else
                if ~isempty(selection)
                    app.OnClosedHandle(selection.NodeData);
                else
                    app.OnClosedHandle([]);
                end
            end
            close(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function on_cancel(app, event)
            app.OnClosedHandle([]);
            close(app.UIFigure);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SelectButton = uibutton(app.GridLayout2, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @on_select_var, true);
            app.SelectButton.Text = 'Select';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @on_cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.Tree = uitree(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selector_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end